classdef SoftwareUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        MenuTab                  matlab.ui.container.Tab
        DimensionlessNumberFunlandLabel  matlab.ui.control.Label
        Panel_2                  matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        StoButton                matlab.ui.control.Button
        Button9                  matlab.ui.control.Button
        Button8                  matlab.ui.control.Button
        Button7                  matlab.ui.control.Button
        Button6                  matlab.ui.control.Button
        Button5                  matlab.ui.control.Button
        Button4                  matlab.ui.control.Button
        MaButton                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        ReButton                 matlab.ui.control.Button
        ReTab                    matlab.ui.container.Tab
        ReAppText                matlab.ui.control.Label
        ReAppTitle               matlab.ui.control.Label
        ReEqnText                matlab.ui.control.Label
        ReEqnLatex               matlab.ui.control.Label
        ReEqnTitle               matlab.ui.control.Label
        ReInterpretText          matlab.ui.control.Label
        ReInterpretationTitle    matlab.ui.control.Label
        ReMenuButton             matlab.ui.control.Button
        SimulatinoButton         matlab.ui.control.Button
        ReTheoryText             matlab.ui.control.Label
        ReTheoryTitle            matlab.ui.control.Label
        ReSimTab                 matlab.ui.container.Tab
        BackButton               matlab.ui.control.Button
        MenuButton_2             matlab.ui.control.Button
        RunButton                matlab.ui.control.Button
        Slider                   matlab.ui.control.RangeSlider
        SliderLabel              matlab.ui.control.Label
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        ReSim2Tab                matlab.ui.container.Tab
        BackButton_4             matlab.ui.control.Button
        MenuButton_7             matlab.ui.control.Button
        RunButton_3              matlab.ui.control.Button
        Slider_2                 matlab.ui.control.RangeSlider
        Slider_2Label            matlab.ui.control.Label
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes2_2                matlab.ui.control.UIAxes
        ReSim3Tab                matlab.ui.container.Tab
        BackButton_5             matlab.ui.control.Button
        MenuButton_8             matlab.ui.control.Button
        RunButton_4              matlab.ui.control.Button
        Slider_3                 matlab.ui.control.RangeSlider
        Slider_3Label            matlab.ui.control.Label
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes2_3                matlab.ui.control.UIAxes
        StoTab                   matlab.ui.container.Tab
        StEqnText                matlab.ui.control.Label
        StEqnLatex               matlab.ui.control.Label
        StEquationTitle          matlab.ui.control.Label
        StInterpretText          matlab.ui.control.Label
        StInterpretTitle         matlab.ui.control.Label
        StAppTitle               matlab.ui.control.Label
        StAppText                matlab.ui.control.Label
        StTheorytext             matlab.ui.control.Label
        StTheoryTitle            matlab.ui.control.Label
        SimulashionButton        matlab.ui.control.Button
        MenuButton_3             matlab.ui.control.Button
        StoSimTab                matlab.ui.container.Tab
        Label_2                  matlab.ui.control.Label
        StokesNumberSlider       matlab.ui.control.Slider
        StokesNumberSliderLabel  matlab.ui.control.Label
        RunButton_2              matlab.ui.control.Button
        BackButton_2             matlab.ui.control.Button
        MenuButton_4             matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        MaTab                    matlab.ui.container.Tab
        MenuButton_5             matlab.ui.control.Button
        MaButton_2               matlab.ui.control.Button
        MaSimTab                 matlab.ui.container.Tab
        BackButton_3             matlab.ui.control.Button
        MenuButton_6             matlab.ui.control.Button
        UIAxes3                  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = func(app)
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton, ReButton
        function ReButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.ReTab;
        end

        % Callback function
        function SliderValueChanging(app, event)
            changingValue = event.Value;
        end

        % Callback function: RunButton, Slider
        function NewReSim(app, event)
            value = app.Slider.Value;
            [ReynoldsSim,wx,wy] = ReSim();
             plot(app.UIAxes,ReynoldsSim,XData=wx, ...
                YData=wy,NodeLabel=[])
            axis(app.UIAxes,"equal")

        end

        % Button pushed function: BackButton_2, StoButton
        function StoButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.StoTab;
        end

        % Button pushed function: BackButton_3, MaButton
        function MaButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.MaTab;
        end

        % Button pushed function: SimulatinoButton
        function SimulatinoButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.ReSimTab;
        end

        % Button pushed function: MenuButton_2, MenuButton_3, 
        % ...and 2 other components
        function MenuButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.MenuTab;
        end

        % Callback function
        function RunButton_2Pushed(app, event)
            
        end

        % Button pushed function: RunButton_2
        function RunStokesSimButtonPushed(app, event)
            app.UIAxes.Visible = true;
            StSim(app.UIAxes, app.StokesNumberSlider.Value);
        end

        % Button pushed function: SimulashionButton
        function SimulashionButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.StoSimTab;
        end

        % Value changed function: StokesNumberSlider
        function StokesNumberSliderValueChanged(app, event)
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MenuTab = uitab(app.TabGroup);
            app.MenuTab.Title = 'Menu';
            app.Panel_2 = uipanel(app.MenuTab);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.ReButton = uibutton(app.GridLayout2, 'push');
            app.ReButton.ButtonPushedFcn = createCallbackFcn(app, @ReButtonPushed, true);
            app.ReButton.Text = 'Re';
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.Text = '';
            app.MaButton = uibutton(app.GridLayout2, 'push');
            app.MaButton.ButtonPushedFcn = createCallbackFcn(app, @MaButtonPushed, true);
            app.MaButton.Text = 'Ma';
            app.Button4 = uibutton(app.GridLayout2, 'push');
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.GridLayout2, 'push');
            app.Button5.Text = 'Button5';
            app.Button6 = uibutton(app.GridLayout2, 'push');
            app.Button6.Text = 'Button6';
            app.Button7 = uibutton(app.GridLayout2, 'push');
            app.Button7.Text = 'Button7';
            app.Button8 = uibutton(app.GridLayout2, 'push');
            app.Button8.Text = 'Button8';
            app.Button9 = uibutton(app.GridLayout2, 'push');
            app.Button9.Text = 'Button9';
            app.StoButton = uibutton(app.GridLayout2, 'push');
            app.StoButton.ButtonPushedFcn = createCallbackFcn(app, @StoButtonPushed, true);
            app.StoButton.Text = 'Sto';
            app.DimensionlessNumberFunlandLabel = uilabel(app.MenuTab);
            app.DimensionlessNumberFunlandLabel.Text = 'Dimensionless Number Funland';
            app.ReTab = uitab(app.TabGroup);
            app.ReTab.Title = 'Re';
            app.ReTheoryTitle = uilabel(app.ReTab);
            app.ReTheoryTitle.Text = 'Theory';
            app.ReTheoryText = uilabel(app.ReTab);
            app.ReTheoryText.Text = 'The Reynolds Number (Re), compares the inertial forces to viscous forces in a fluid flow.';
            app.SimulatinoButton = uibutton(app.ReTab, 'push');
            app.SimulatinoButton.ButtonPushedFcn = createCallbackFcn(app, @SimulatinoButtonPushed, true);
            app.SimulatinoButton.Text = 'Simulatino';
            app.ReMenuButton = uibutton(app.ReTab, 'push');
            app.ReMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.ReMenuButton.Text = 'Menu';
            app.ReInterpretationTitle = uilabel(app.ReTab);
            app.ReInterpretationTitle.Text = 'Interpretation';
            app.ReInterpretText = uilabel(app.ReTab);
            app.ReInterpretText.Text = {'A low Re (<2000) signifies a laminar (smooth) flow whereas a high Re (>4000) signifies a turbulent (chaotic) flow dominated by eddies. '; 'Between 2000<Re<4000 exists a transitional region where small disturbances can cause turbulence.'; ''};
            app.ReEqnTitle = uilabel(app.ReTab);
            app.ReEqnTitle.Text = 'Equation';
            app.ReEqnLatex = uilabel(app.ReTab);
            app.ReEqnLatex.Interpreter = 'latex';
            app.ReEqnLatex.Text = '$$Re = \frac{\rho U L}{\mu}$$';
            app.ReEqnText = uilabel(app.ReTab);
            app.ReEqnText.Interpreter = 'latex';
            app.ReEqnText.Text = 'where \rho\, is the fluid density, U is the free flow velocity, L is the characteristic length, and \mu\,  is the dynamic viscosity of the fluid.';
            app.ReAppTitle = uilabel(app.ReTab);
            app.ReAppTitle.Text = 'Application';
            app.ReAppText = uilabel(app.ReTab);
            app.ReAppText.Text = 'The Reynolds Number can be used to predict the flow in a pipe, around an airfoil, and past a car body.';
            app.ReSimTab = uitab(app.TabGroup);
            app.ReSimTab.Title = 'ReSim';
            app.UIAxes2 = uiaxes(app.ReSimTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_2 = uiaxes(app.ReSimTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.SliderLabel = uilabel(app.ReSimTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.ReSimTab, 'range');
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @NewReSim, true);
            app.RunButton = uibutton(app.ReSimTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @NewReSim, true);
            app.RunButton.Text = 'Run';
            app.MenuButton_2 = uibutton(app.ReSimTab, 'push');
            app.MenuButton_2.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton_2.Text = 'Menu';
            app.BackButton = uibutton(app.ReSimTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @ReButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ReSim2Tab = uitab(app.TabGroup);
            app.ReSim2Tab.Title = 'ReSim2';
            app.UIAxes2_2 = uiaxes(app.ReSim2Tab);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes_3 = uiaxes(app.ReSim2Tab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.Slider_2Label = uilabel(app.ReSim2Tab);
            app.Slider_2Label.Text = 'Slider';
            app.Slider_2 = uislider(app.ReSim2Tab, 'range');
            app.RunButton_3 = uibutton(app.ReSim2Tab, 'push');
            app.RunButton_3.Text = 'Run';
            app.MenuButton_7 = uibutton(app.ReSim2Tab, 'push');
            app.MenuButton_7.Text = 'Menu';
            app.BackButton_4 = uibutton(app.ReSim2Tab, 'push');
            app.BackButton_4.Text = 'Back';
            app.ReSim3Tab = uitab(app.TabGroup);
            app.ReSim3Tab.Title = 'ReSim3';
            app.UIAxes2_3 = uiaxes(app.ReSim3Tab);
            title(app.UIAxes2_3, 'Title')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes_4 = uiaxes(app.ReSim3Tab);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.Slider_3Label = uilabel(app.ReSim3Tab);
            app.Slider_3Label.Text = 'Slider';
            app.Slider_3 = uislider(app.ReSim3Tab, 'range');
            app.RunButton_4 = uibutton(app.ReSim3Tab, 'push');
            app.RunButton_4.Text = 'Run';
            app.MenuButton_8 = uibutton(app.ReSim3Tab, 'push');
            app.MenuButton_8.Text = 'Menu';
            app.BackButton_5 = uibutton(app.ReSim3Tab, 'push');
            app.BackButton_5.Text = 'Back';
            app.StoTab = uitab(app.TabGroup);
            app.StoTab.Title = 'Sto';
            app.MenuButton_3 = uibutton(app.StoTab, 'push');
            app.MenuButton_3.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton_3.Text = 'Menu';
            app.SimulashionButton = uibutton(app.StoTab, 'push');
            app.SimulashionButton.ButtonPushedFcn = createCallbackFcn(app, @SimulashionButtonPushed, true);
            app.SimulashionButton.Text = 'Simulashion';
            app.StTheoryTitle = uilabel(app.StoTab);
            app.StTheoryTitle.Text = 'Theory';
            app.StTheorytext = uilabel(app.StoTab);
            app.StTheorytext.Text = 'The Stokes Number (Stk) compares particle inertia to fluid drag forces which describes how well particles follow surrounding fluid motion.';
            app.StAppText = uilabel(app.StoTab);
            app.StAppText.Text = {'Used in inkjet printing, aerosol transport, dust collection and fuel injection systems, as predicting droplets formed will follow airflow or impact surfaces.'; ''};
            app.StAppTitle = uilabel(app.StoTab);
            app.StAppTitle.Text = {'Application'; ''};
            app.StInterpretTitle = uilabel(app.StoTab);
            app.StInterpretTitle.Text = 'Interpretation';
            app.StInterpretText = uilabel(app.StoTab);
            app.StInterpretText.Text = {'Low values St ~0.1 means droplets closely follow fluid streamlines, but as St increases they tend to ignore the streamlines more.'; ''; ''};
            app.StEquationTitle = uilabel(app.StoTab);
            app.StEquationTitle.Text = 'Equation';
            app.StEqnLatex = uilabel(app.StoTab);
            app.StEqnLatex.Interpreter = 'latex';
            app.StEqnLatex.Text = '$$St = \frac{\rho U R}{\mu_g}$$';
            app.StEqnText = uilabel(app.StoTab);
            app.StEqnText.Interpreter = 'latex';
            app.StEqnText.Text = 'where \rho\, is the density of the fluid, U is the speed of the fluid droplet, R is the droplets radius, and $\mu_g\,$is the dynamic viscosity of the surrounding fluid (e.g. air).';
            app.StoSimTab = uitab(app.TabGroup);
            app.StoSimTab.Title = 'StoSim';
            app.UIAxes = uiaxes(app.StoSimTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MenuButton_4 = uibutton(app.StoSimTab, 'push');
            app.MenuButton_4.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton_4.Text = 'Menu';
            app.BackButton_2 = uibutton(app.StoSimTab, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @StoButtonPushed, true);
            app.BackButton_2.Text = 'Back';
            app.RunButton_2 = uibutton(app.StoSimTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunStokesSimButtonPushed, true);
            app.RunButton_2.Text = 'Run';
            app.StokesNumberSliderLabel = uilabel(app.StoSimTab);
            app.StokesNumberSliderLabel.Text = 'Stokes Number';
            app.StokesNumberSlider = uislider(app.StoSimTab);
            app.StokesNumberSlider.Limits = [0.1 10];
            app.StokesNumberSlider.MajorTicks = [0.1 2 4 6 8 10];
            app.StokesNumberSlider.ValueChangedFcn = createCallbackFcn(app, @StokesNumberSliderValueChanged, true);
            app.StokesNumberSlider.Value = 0.1;
            app.Label_2 = uilabel(app.StoSimTab);
            app.Label_2.Text = 'The particles have an initial horizontal velocity and enter a vortex. You will observe that at low St, the particles have weak inertia and are easily redirected by the vortex. At high St, the particles can persist on their original trajectory.';
            app.MaTab = uitab(app.TabGroup);
            app.MaTab.Title = 'Ma';
            app.MaButton_2 = uibutton(app.MaTab, 'push');
            app.MaButton_2.Text = 'Ma';
            app.MenuButton_5 = uibutton(app.MaTab, 'push');
            app.MenuButton_5.Text = 'Menu';
            app.MaSimTab = uitab(app.TabGroup);
            app.MaSimTab.Title = 'MaSim';
            app.UIAxes3 = uiaxes(app.MaSimTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.MenuButton_6 = uibutton(app.MaSimTab, 'push');
            app.MenuButton_6.Text = 'Menu';
            app.BackButton_3 = uibutton(app.MaSimTab, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @MaButtonPushed, true);
            app.BackButton_3.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SoftwareUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end